classdef BIGMACS_UI < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                 matlab.ui.container.GridLayout
        CancelButton               matlab.ui.control.Button
        RunButton                  matlab.ui.control.Button
        DirectoryLabel             matlab.ui.control.Label
        PrintfiguresDropDown       matlab.ui.control.DropDown
        PrintfiguresDropDownLabel  matlab.ui.control.Label
        InputmodeDropDown          matlab.ui.control.DropDown
        InputmodeDropDownLabel     matlab.ui.control.Label
        SelectfolderButton         matlab.ui.control.Button
        WelcometoBIGMACSLabel      matlab.ui.control.Label
    end

    properties (Access = public)
        inputFile string = "/"; % Directory (folder) of input files
        inputMode string = "age_model_construction";
        printFig string = "show"; % Whether to show or hide the figures
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(comp, event)
            comp.inputFile = uigetdir();
        end

        % Value changed function: PrintfiguresDropDown
        function PrintfiguresDropDownValueChanged(comp, event)
            comp.printFig = comp.PrintfiguresDropDown.Value;
        end

        % Value changed function: InputmodeDropDown
        function InputmodeDropDownValueChanged(comp, event)
            comp.inputMode = comp.InputmodeDropDown.Value;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(comp, event)
            close all;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)
            BIGMACS(comp.inputFile, comp.inputMode, comp.printFig);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.DirectoryLabel.Text = comp.inputFile;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 640 480];
            comp.BackgroundColor = [0.302 0.749 0.9294];
            comp.GridLayout = uigridlayout(comp);
            comp.WelcometoBIGMACSLabel = uilabel(comp.GridLayout);
            comp.WelcometoBIGMACSLabel.Text = 'Welcome to BIGMACS';
            comp.SelectfolderButton = uibutton(comp.GridLayout, 'push');
            comp.SelectfolderButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectfolderButtonPushed, true);
            comp.SelectfolderButton.Text = 'Select folder';
            comp.InputmodeDropDownLabel = uilabel(comp.GridLayout);
            comp.InputmodeDropDownLabel.Text = 'Input mode';
            comp.InputmodeDropDown = uidropdown(comp.GridLayout);
            comp.InputmodeDropDown.Items = {'Age model construction', 'Stack construction'};
            comp.InputmodeDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @InputmodeDropDownValueChanged, true);
            comp.InputmodeDropDown.Value = 'Age model construction';
            comp.PrintfiguresDropDownLabel = uilabel(comp.GridLayout);
            comp.PrintfiguresDropDownLabel.Text = 'Print figures';
            comp.PrintfiguresDropDown = uidropdown(comp.GridLayout);
            comp.PrintfiguresDropDown.Items = {'Show', 'Hide'};
            comp.PrintfiguresDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PrintfiguresDropDownValueChanged, true);
            comp.PrintfiguresDropDown.Value = 'Show';
            comp.DirectoryLabel = uilabel(comp.GridLayout);
            comp.DirectoryLabel.Text = '/';
            comp.RunButton = uibutton(comp.GridLayout, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Text = 'Run';
            comp.CancelButton = uibutton(comp.GridLayout, 'push');
            comp.CancelButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CancelButtonPushed, true);
            comp.CancelButton.Text = 'Cancel';
        end
    end
end